classdef ffauxivsolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FauxHollowsSolverUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout6                matlab.ui.container.GridLayout
        NameLabel                  matlab.ui.control.Label
        TurnsLabel                 matlab.ui.control.Label
        ResetButton                matlab.ui.control.Button
        UndoButton                 matlab.ui.control.Button
        RetellCheckBox             matlab.ui.control.CheckBox
        GridLayout4                matlab.ui.container.GridLayout
        Image_n                    matlab.ui.control.Image
        Image_f                    matlab.ui.control.Image
        Image_c4                   matlab.ui.control.Image
        Image_c3                   matlab.ui.control.Image
        Image_s6h                  matlab.ui.control.Image
        Image_s5h                  matlab.ui.control.Image
        Image_c2                   matlab.ui.control.Image
        Image_c1                   matlab.ui.control.Image
        Image_s4h                  matlab.ui.control.Image
        Image_s3h                  matlab.ui.control.Image
        Image_g4                   matlab.ui.control.Image
        Image_g3                   matlab.ui.control.Image
        Image_s2h                  matlab.ui.control.Image
        Image_s1h                  matlab.ui.control.Image
        Image_g2                   matlab.ui.control.Image
        Image_g1                   matlab.ui.control.Image
        Image_s6v                  matlab.ui.control.Image
        Image_s5v                  matlab.ui.control.Image
        Image_s4v                  matlab.ui.control.Image
        Image_m                    matlab.ui.control.Image
        Image_s3v                  matlab.ui.control.Image
        Image_s2v                  matlab.ui.control.Image
        Image_s1v                  matlab.ui.control.Image
        Image_x                    matlab.ui.control.Image
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout5                matlab.ui.container.GridLayout
        Panel_31                   matlab.ui.control.Image
        Panel_32                   matlab.ui.control.Image
        Panel_33                   matlab.ui.control.Image
        Panel_34                   matlab.ui.control.Image
        Panel_35                   matlab.ui.control.Image
        Panel_36                   matlab.ui.control.Image
        Panel_30                   matlab.ui.control.Image
        Panel_29                   matlab.ui.control.Image
        Panel_28                   matlab.ui.control.Image
        Panel_27                   matlab.ui.control.Image
        Panel_26                   matlab.ui.control.Image
        Panel_25                   matlab.ui.control.Image
        Panel_24                   matlab.ui.control.Image
        Panel_23                   matlab.ui.control.Image
        Panel_22                   matlab.ui.control.Image
        Panel_21                   matlab.ui.control.Image
        Panel_20                   matlab.ui.control.Image
        Panel_19                   matlab.ui.control.Image
        Panel_18                   matlab.ui.control.Image
        Panel_17                   matlab.ui.control.Image
        Panel_16                   matlab.ui.control.Image
        Panel_15                   matlab.ui.control.Image
        Panel_14                   matlab.ui.control.Image
        Panel_13                   matlab.ui.control.Image
        Panel_12                   matlab.ui.control.Image
        Panel_11                   matlab.ui.control.Image
        Panel_10                   matlab.ui.control.Image
        Panel_9                    matlab.ui.control.Image
        Panel_8                    matlab.ui.control.Image
        Panel_7                    matlab.ui.control.Image
        Panel_6                    matlab.ui.control.Image
        Panel_5                    matlab.ui.control.Image
        Panel_4                    matlab.ui.control.Image
        Panel_3                    matlab.ui.control.Image
        Panel_2                    matlab.ui.control.Image
        Panel_1                    matlab.ui.control.Image
    end

    properties (Access = private)
        revealedMetadata = nan(6)
        lastRevealedMetadata = nan(6)
        panels = [];
        focusedPanel = 0;
        values = struct("blades", 15+53, "gift", 25, "coffer", 35, "fox", 100);
    end
    
    methods (Access = private)

        % Generate suggestions and update game
        function recalculate(app)
            drawGame(app);
        end
        
        function drawGame(app)
            isRevealed = ~isnan(app.revealedMetadata);
            if sum(app.revealedMetadata == 0, "all") < 5
                possible = checkPossible(app);
                app.TurnsLabel.Text = "Turns: 11";
                app.revealedMetadata(all(possible.Metadata == 0, 3)) = 0;
                isRevealed = ~isnan(app.revealedMetadata);
                rec = find(any(possible.Metadata == 0, 3) & ~isRevealed);
            end
            if sum(app.revealedMetadata == 0, "all") == 5
                possible = checkPossible(app);
                turns = 11 - sum(app.revealedMetadata > 0, "all");
                app.TurnsLabel.Text = "Turns: " + turns;
                metadataTypes = 22;
                pMetadata = zeros(6, 6, metadataTypes);
                for iM = 1:metadataTypes
                    pMetadata(:, :, iM) = sum((possible.Metadata == iM).*possible.Chances, 3);
                end
        
                shannon = -sum(pMetadata.*log2(pMetadata).*(pMetadata>1E-5), 3, "omitnan");
                
                valueRatio = zeros(6, 6);
                boxPanels = 4 - sum(app.revealedMetadata == permute(2:9, [3 1 2]), "all");
                swordPanels = 6 - sum(app.revealedMetadata == permute(10:21, [3 1 2]), "all");
                boxCost = zeros(6, 6);
                swordCost = zeros(6, 6);
                foxCost = zeros(6, 6);
                pBox = sum(pMetadata(:, :, 2:9), 3);
                pSword = sum(pMetadata(:, :, 10:21), 3);
                pFox = sum(pMetadata(:, :, 22), "all");
                nFox = sum(pMetadata(:, :, 22) > 1E-5, "all");
                boxCommitment = (pBox > 0.99999 | (pBox > 1E-5 & (max(pSword, [], "all") > 0.99999 && nFox <= 4))).*(boxPanels-1);
                swordCommitment = (pSword > 0.99999 | (pSword > 1E-5 & (max(pBox, [], "all") > 0.99999 && nFox <= 4))).*(swordPanels-1);
                foxCommitment = (pMetadata(:, :, 22) > 1E-5 & sum(pMetadata(:, :, 2:21), 3) < 1E-5)*(nFox-1);
                nFoxRaw = nFox;
                nFox = min(4, nFox);
        
                if turns >= boxPanels && boxPanels > 0
                    boxValueRatio = sum(pMetadata(:, :, 2:5)*app.values.gift + pMetadata(:, :, 6:9)*app.values.coffer, 3).*~isRevealed/boxPanels;
                    valueRatio = valueRatio + boxValueRatio;
                    boxCost = min(0, pBox - min(1, min(1+swordCommitment+foxCommitment, 1/max(pBox, [], "all")-(turns-swordCommitment-foxCommitment-boxPanels))*max(pBox, [], "all")))*sum(boxValueRatio, "all");
                end
                if turns >= swordPanels  && swordPanels > 0
                    swordValueRatio = sum(pMetadata(:, :, 10:21)*app.values.blades, 3).*~isRevealed/swordPanels;
                    valueRatio = valueRatio + swordValueRatio;
                    swordCost = min(0, pSword - min(1, min(1+boxCommitment+foxCommitment, 1/max(pSword, [], "all")-(turns-boxCommitment-foxCommitment-swordPanels))*max(pSword, [], "all")))*sum(swordValueRatio, "all");
                end
                if ~any(app.revealedMetadata == 22, "all")
                    foxValue = pMetadata(:, :, 22)*app.values.fox;
                    valueRatio = valueRatio + foxValue;
                    foxCost = min(0, pMetadata(:, :, 22) - min(pFox, min(1+boxCommitment+swordCommitment, nFox-(turns-boxCommitment-swordCommitment-1))*max(pMetadata(:, :, 22), [], "all")))*app.values.fox;
                end
        
                valueMissRatio = valueRatio + min(min(boxCost, swordCost), foxCost);
                valueRatio(isRevealed) = nan;
                valueMissRatio(isRevealed) = nan;
                valueRatio(pMetadata(:, :, 1) > 0.99999) = nan;
                valueMissRatio(pMetadata(:, :, 1) > 0.99999) = nan;
            
                shannonLogValueRatio = log(valueRatio) + shannon;
        
                if turns == 11
                    maxValue = max(shannonLogValueRatio, [], "all");
                    rec = find(maxValue == shannonLogValueRatio);
                else
                    maxValue = max(valueMissRatio, [], "all");
                    rec = find(maxValue == valueMissRatio);
                end
            end

            knownName = all(possible.Names{1}' == reshape([possible.Names{:}], 6, []), 2);
            forcedName = "______";
            forcedName{1}(knownName) = possible.Names{1}(knownName);
            app.NameLabel.Text = forcedName;

            for iP = 1:36
                if iP == app.focusedPanel
                    app.panels(iP).ImageSource = "panels/focus.png";
                elseif ~isRevealed(iP)
                    if sum(app.revealedMetadata == 0, "all") < 5
                        if any(iP == rec)
                            app.panels(iP).ImageSource = "panels/recx.png";
                        else
                            app.panels(iP).ImageSource = "panels/n.png";
                        end
                    else
                        if any(iP == rec)
                            app.panels(iP).ImageSource = "panels/rec.png";
                        elseif pSword(iP) > 0.99999
                            app.panels(iP).ImageSource = "panels/s.png";
                        elseif pBox(iP) > 0.99999
                            if any(app.revealedMetadata == permute(2:5, [3 1 2]), "all")
                                app.panels(iP).ImageSource = "panels/g.png";
                            elseif any(app.revealedMetadata == permute(6:9, [3 1 2]), "all")
                                app.panels(iP).ImageSource = "panels/c.png";
                            else
                                app.panels(iP).ImageSource = "panels/b.png";
                            end
                        elseif nFoxRaw <= 4 && pMetadata(iP+21*36) > 1E-5
                            app.panels(iP).ImageSource = "panels/fm.png";
                        else
                            app.panels(iP).ImageSource = "panels/n.png";
                        end
                    end
                else
                    switch (app.revealedMetadata(iP))
                        case 0
                            app.panels(iP).ImageSource = "panels/x.png";
                        case 1
                            app.panels(iP).ImageSource = "panels/m.png";
                        case 2
                            app.panels(iP).ImageSource = "panels/g1.png";
                        case 3
                            app.panels(iP).ImageSource = "panels/g2.png";
                        case 4
                            app.panels(iP).ImageSource = "panels/g3.png";
                        case 5
                            app.panels(iP).ImageSource = "panels/g4.png";
                        case 6
                            app.panels(iP).ImageSource = "panels/c1.png";
                        case 7
                            app.panels(iP).ImageSource = "panels/c2.png";
                        case 8
                            app.panels(iP).ImageSource = "panels/c3.png";
                        case 9
                            app.panels(iP).ImageSource = "panels/c4.png";
                        case 10
                            app.panels(iP).ImageSource = "panels/s1v.png";
                        case 11
                            app.panels(iP).ImageSource = "panels/s2v.png";
                        case 12
                            app.panels(iP).ImageSource = "panels/s3v.png";
                        case 13
                            app.panels(iP).ImageSource = "panels/s4v.png";
                        case 14
                            app.panels(iP).ImageSource = "panels/s5v.png";
                        case 15
                            app.panels(iP).ImageSource = "panels/s6v.png";
                        case 16
                            app.panels(iP).ImageSource = "panels/s1h.png";
                        case 17
                            app.panels(iP).ImageSource = "panels/s2h.png";
                        case 18
                            app.panels(iP).ImageSource = "panels/s3h.png";
                        case 19
                            app.panels(iP).ImageSource = "panels/s4h.png";
                        case 20
                            app.panels(iP).ImageSource = "panels/s5h.png";
                        case 21
                            app.panels(iP).ImageSource = "panels/s6h.png";
                        case 22
                            app.panels(iP).ImageSource = "panels/f.png";
                    end
                end
            end
        end
        
        function possible = checkPossible(app)
            load patterns.mat names chances metadata

            possibleFilter = all(app.revealedMetadata == metadata | isnan(app.revealedMetadata), [1 2]);
            possible.Metadata = metadata(:, :, possibleFilter);
            possible.Names = names(possibleFilter);
            possible.Chances = chances(possibleFilter)/sum(chances(possibleFilter));
        end
        
        function showOptions(app)
            possible = checkPossible(app);

            app.Image_x.Visible = false;
            app.Image_m.Visible = false;
            app.Image_g1.Visible = false;
            app.Image_g2.Visible = false;
            app.Image_g3.Visible = false;
            app.Image_g4.Visible = false;
            app.Image_c1.Visible = false;
            app.Image_c2.Visible = false;
            app.Image_c3.Visible = false;
            app.Image_c4.Visible = false;
            app.Image_s1v.Visible = false;
            app.Image_s2v.Visible = false;
            app.Image_s3v.Visible = false;
            app.Image_s4v.Visible = false;
            app.Image_s5v.Visible = false;
            app.Image_s6v.Visible = false;
            app.Image_s1h.Visible = false;
            app.Image_s2h.Visible = false;
            app.Image_s3h.Visible = false;
            app.Image_s4h.Visible = false;
            app.Image_s5h.Visible = false;
            app.Image_s6h.Visible = false;
            app.Image_f.Visible = false;
            if sum(app.revealedMetadata == 0, "all") < 5
                app.Image_x.Visible = true;
            else
                if app.focusedPanel == 0
                    filterMatrix = isnan(app.revealedMetadata);
                else
                    filterMatrix = false(6);
                    filterMatrix(app.focusedPanel) = true;
                end
                for type = unique(possible.Metadata(repmat(filterMatrix, 1, 1, size(possible.Metadata, 3))))'
                    switch type
                        case 0
                            app.Image_x.Visible = true;
                        case 1
                            app.Image_m.Visible = true;
                        case 2
                            app.Image_g1.Visible = true;
                        case 3
                            app.Image_g2.Visible = true;
                        case 4
                            app.Image_g3.Visible = true;
                        case 5
                            app.Image_g4.Visible = true;
                        case 6
                            app.Image_c1.Visible = true;
                        case 7
                            app.Image_c2.Visible = true;
                        case 8
                            app.Image_c3.Visible = true;
                        case 9
                            app.Image_c4.Visible = true;
                        case 10
                            app.Image_s1v.Visible = true;
                        case 11
                            app.Image_s2v.Visible = true;
                        case 12
                            app.Image_s3v.Visible = true;
                        case 13
                            app.Image_s4v.Visible = true;
                        case 14
                            app.Image_s5v.Visible = true;
                        case 15
                            app.Image_s6v.Visible = true;
                        case 16
                            app.Image_s1h.Visible = true;
                        case 17
                            app.Image_s2h.Visible = true;
                        case 18
                            app.Image_s3h.Visible = true;
                        case 19
                            app.Image_s4h.Visible = true;
                        case 20
                            app.Image_s5h.Visible = true;
                        case 21
                            app.Image_s6h.Visible = true;
                        case 22
                            app.Image_f.Visible = true;
                    end
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.panels = [[app.Panel_1; ...
                app.Panel_2; ...
                app.Panel_3; ...
                app.Panel_4; ...
                app.Panel_5; ...
                app.Panel_6] ...
                [app.Panel_7; ...
                app.Panel_8; ...
                app.Panel_9; ...
                app.Panel_10; ...
                app.Panel_11; ...
                app.Panel_12] ...
                [app.Panel_13; ...
                app.Panel_14; ...
                app.Panel_15; ...
                app.Panel_16; ...
                app.Panel_17; ...
                app.Panel_18] ...
                [app.Panel_19; ...
                app.Panel_20; ...
                app.Panel_21; ...
                app.Panel_22; ...
                app.Panel_23; ...
                app.Panel_24] ...
                [app.Panel_25; ...
                app.Panel_26; ...
                app.Panel_27; ...
                app.Panel_28; ...
                app.Panel_29; ...
                app.Panel_30] ...
                [app.Panel_31; ...
                app.Panel_32; ...
                app.Panel_33; ...
                app.Panel_34; ...
                app.Panel_35; ...
                app.Panel_36]];
            recalculate(app);
        end

        % Image clicked function: Panel_1, Panel_10, Panel_11, Panel_12, 
        % ...and 32 other components
        function PanelSelected(app, event)
            app.focusedPanel = event.Source.Layout.Row + 6*(event.Source.Layout.Column - 1);
            if sum(app.revealedMetadata == 0, "all") < 5
                app.lastRevealedMetadata = app.revealedMetadata;
                app.revealedMetadata(app.focusedPanel) = 0;
                app.focusedPanel = 0;
            else
                filterMatrix = false(6);
                filterMatrix(app.focusedPanel) = true;
                possible = checkPossible(app);
                if all(possible.Metadata(app.focusedPanel) == possible.Metadata(repmat(filterMatrix, 1, 1, size(possible.Metadata, 3))))
                    app.lastRevealedMetadata = app.revealedMetadata;
                    app.revealedMetadata(app.focusedPanel) = possible.Metadata(app.focusedPanel);
                    app.focusedPanel = 0;
                else
                    app.Image_x.Enable = false;
                    app.Image_m.Enable = true;
                    app.Image_g1.Enable = true;
                    app.Image_g2.Enable = true;
                    app.Image_g3.Enable = true;
                    app.Image_g4.Enable = true;
                    app.Image_c1.Enable = true;
                    app.Image_c2.Enable = true;
                    app.Image_c3.Enable = true;
                    app.Image_c4.Enable = true;
                    app.Image_s1v.Enable = true;
                    app.Image_s2v.Enable = true;
                    app.Image_s3v.Enable = true;
                    app.Image_s4v.Enable = true;
                    app.Image_s5v.Enable = true;
                    app.Image_s6v.Enable = true;
                    app.Image_s1h.Enable = true;
                    app.Image_s2h.Enable = true;
                    app.Image_s3h.Enable = true;
                    app.Image_s4h.Enable = true;
                    app.Image_s5h.Enable = true;
                    app.Image_s6h.Enable = true;
                    app.Image_f.Enable = true;
                    app.Image_n.Enable = true;
                end
            end
            drawGame(app);
            showOptions(app);
        end

        % Value changed function: RetellCheckBox
        function Tell_StateChanged(app, event)
            switch app.RetellCheckBox.Value
                case true
                    app.values.blades = 15;
                case false
                    app.values.blades = 15+53;
            end
            drawGame(app);
        end

        % Button pushed function: ResetButton
        function Reset_ButtonPushed(app, event)
            app.lastRevealedMetadata = app.revealedMetadata;
            app.revealedMetadata(:) = nan;
            app.focusedPanel = 0;
            drawGame(app);
            app.Image_x.Enable = false;
            app.Image_m.Enable = false;
            app.Image_g1.Enable = false;
            app.Image_g2.Enable = false;
            app.Image_g3.Enable = false;
            app.Image_g4.Enable = false;
            app.Image_c1.Enable = false;
            app.Image_c2.Enable = false;
            app.Image_c3.Enable = false;
            app.Image_c4.Enable = false;
            app.Image_s1v.Enable = false;
            app.Image_s2v.Enable = false;
            app.Image_s3v.Enable = false;
            app.Image_s4v.Enable = false;
            app.Image_s5v.Enable = false;
            app.Image_s6v.Enable = false;
            app.Image_s1h.Enable = false;
            app.Image_s2h.Enable = false;
            app.Image_s3h.Enable = false;
            app.Image_s4h.Enable = false;
            app.Image_s5h.Enable = false;
            app.Image_s6h.Enable = false;
            app.Image_f.Enable = false;
            app.Image_n.Enable = false;
            showOptions(app);
        end

        % Button pushed function: UndoButton
        function Undo_UndoButtonPushed(app, event)
            temp = app.lastRevealedMetadata;
            app.lastRevealedMetadata = app.revealedMetadata;
            app.revealedMetadata = temp;
            app.focusedPanel = 0;
            drawGame(app);
            app.Image_x.Enable = false;
            app.Image_m.Enable = false;
            app.Image_g1.Enable = false;
            app.Image_g2.Enable = false;
            app.Image_g3.Enable = false;
            app.Image_g4.Enable = false;
            app.Image_c1.Enable = false;
            app.Image_c2.Enable = false;
            app.Image_c3.Enable = false;
            app.Image_c4.Enable = false;
            app.Image_s1v.Enable = false;
            app.Image_s2v.Enable = false;
            app.Image_s3v.Enable = false;
            app.Image_s4v.Enable = false;
            app.Image_s5v.Enable = false;
            app.Image_s6v.Enable = false;
            app.Image_s1h.Enable = false;
            app.Image_s2h.Enable = false;
            app.Image_s3h.Enable = false;
            app.Image_s4h.Enable = false;
            app.Image_s5h.Enable = false;
            app.Image_s6h.Enable = false;
            app.Image_f.Enable = false;
            app.Image_n.Enable = false;
            showOptions(app);
        end

        % Image clicked function: Image_c1, Image_c2, Image_c3, Image_c4, 
        % ...and 20 other components
        function RevealReported(app, event)
            app.lastRevealedMetadata = app.revealedMetadata;
            if strcmp(event.Source.Tag, "nan")
                app.revealedMetadata(app.focusedPanel) = nan;
            else
                app.revealedMetadata(app.focusedPanel) = str2double(event.Source.Tag);
            end
            app.focusedPanel = 0;
            drawGame(app)
            app.Image_x.Enable = false;
            app.Image_m.Enable = false;
            app.Image_g1.Enable = false;
            app.Image_g2.Enable = false;
            app.Image_g3.Enable = false;
            app.Image_g4.Enable = false;
            app.Image_c1.Enable = false;
            app.Image_c2.Enable = false;
            app.Image_c3.Enable = false;
            app.Image_c4.Enable = false;
            app.Image_s1v.Enable = false;
            app.Image_s2v.Enable = false;
            app.Image_s3v.Enable = false;
            app.Image_s4v.Enable = false;
            app.Image_s5v.Enable = false;
            app.Image_s6v.Enable = false;
            app.Image_s1h.Enable = false;
            app.Image_s2h.Enable = false;
            app.Image_s3h.Enable = false;
            app.Image_s4h.Enable = false;
            app.Image_s5h.Enable = false;
            app.Image_s6h.Enable = false;
            app.Image_f.Enable = false;
            app.Image_n.Enable = false;
            showOptions(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FauxHollowsSolverUIFigure = uifigure('Visible', 'off');
            app.FauxHollowsSolverUIFigure.Name = 'Faux Hollows Solver';
            app.GridLayout = uigridlayout(app.FauxHollowsSolverUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.Panel_1 = uiimage(app.GridLayout5);
            app.Panel_1.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_1.Tag = '1';
            app.Panel_1.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_2 = uiimage(app.GridLayout5);
            app.Panel_2.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_2.Tag = '2';
            app.Panel_2.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_3 = uiimage(app.GridLayout5);
            app.Panel_3.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_3.Tag = '3';
            app.Panel_3.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_4 = uiimage(app.GridLayout5);
            app.Panel_4.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_4.Tag = '4';
            app.Panel_4.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_5 = uiimage(app.GridLayout5);
            app.Panel_5.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_5.Tag = '5';
            app.Panel_5.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_6 = uiimage(app.GridLayout5);
            app.Panel_6.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_6.Tag = '6';
            app.Panel_6.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_7 = uiimage(app.GridLayout5);
            app.Panel_7.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_7.Tag = '7';
            app.Panel_7.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_8 = uiimage(app.GridLayout5);
            app.Panel_8.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_8.Tag = '8';
            app.Panel_8.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_9 = uiimage(app.GridLayout5);
            app.Panel_9.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_9.Tag = '9';
            app.Panel_9.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_10 = uiimage(app.GridLayout5);
            app.Panel_10.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_10.Tag = '10';
            app.Panel_10.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_11 = uiimage(app.GridLayout5);
            app.Panel_11.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_11.Tag = '11';
            app.Panel_11.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_12 = uiimage(app.GridLayout5);
            app.Panel_12.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_12.Tag = '12';
            app.Panel_12.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_13 = uiimage(app.GridLayout5);
            app.Panel_13.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_13.Tag = '13';
            app.Panel_13.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_14 = uiimage(app.GridLayout5);
            app.Panel_14.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_14.Tag = '14';
            app.Panel_14.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_15 = uiimage(app.GridLayout5);
            app.Panel_15.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_15.Tag = '15';
            app.Panel_15.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_16 = uiimage(app.GridLayout5);
            app.Panel_16.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_16.Tag = '16';
            app.Panel_16.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_17 = uiimage(app.GridLayout5);
            app.Panel_17.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_17.Tag = '17';
            app.Panel_17.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_18 = uiimage(app.GridLayout5);
            app.Panel_18.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_18.Tag = '18';
            app.Panel_18.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_19 = uiimage(app.GridLayout5);
            app.Panel_19.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_19.Tag = '19';
            app.Panel_19.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_20 = uiimage(app.GridLayout5);
            app.Panel_20.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_20.Tag = '20';
            app.Panel_20.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_21 = uiimage(app.GridLayout5);
            app.Panel_21.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_21.Tag = '21';
            app.Panel_21.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_22 = uiimage(app.GridLayout5);
            app.Panel_22.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_22.Tag = '22';
            app.Panel_22.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_23 = uiimage(app.GridLayout5);
            app.Panel_23.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_23.Tag = '23';
            app.Panel_23.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_24 = uiimage(app.GridLayout5);
            app.Panel_24.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_24.Tag = '24';
            app.Panel_24.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_25 = uiimage(app.GridLayout5);
            app.Panel_25.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_25.Tag = '25';
            app.Panel_25.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_26 = uiimage(app.GridLayout5);
            app.Panel_26.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_26.Tag = '26';
            app.Panel_26.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_27 = uiimage(app.GridLayout5);
            app.Panel_27.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_27.Tag = '27';
            app.Panel_27.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_28 = uiimage(app.GridLayout5);
            app.Panel_28.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_28.Tag = '28';
            app.Panel_28.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_29 = uiimage(app.GridLayout5);
            app.Panel_29.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_29.Tag = '29';
            app.Panel_29.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_30 = uiimage(app.GridLayout5);
            app.Panel_30.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_30.Tag = '30';
            app.Panel_30.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_36 = uiimage(app.GridLayout5);
            app.Panel_36.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_36.Tag = '36';
            app.Panel_36.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_35 = uiimage(app.GridLayout5);
            app.Panel_35.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_35.Tag = '35';
            app.Panel_35.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_34 = uiimage(app.GridLayout5);
            app.Panel_34.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_34.Tag = '34';
            app.Panel_34.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_33 = uiimage(app.GridLayout5);
            app.Panel_33.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_33.Tag = '33';
            app.Panel_33.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_32 = uiimage(app.GridLayout5);
            app.Panel_32.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_32.Tag = '32';
            app.Panel_32.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.Panel_31 = uiimage(app.GridLayout5);
            app.Panel_31.ImageClickedFcn = createCallbackFcn(app, @PanelSelected, true);
            app.Panel_31.Tag = '31';
            app.Panel_31.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Image_x = uiimage(app.GridLayout4);
            app.Image_x.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_x.Tag = '0';
            app.Image_x.Enable = 'off';
            app.Image_x.ImageSource = fullfile(pathToMLAPP, 'panels', 'x.png');
            app.Image_s1v = uiimage(app.GridLayout4);
            app.Image_s1v.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_s1v.Tag = '10';
            app.Image_s1v.Enable = 'off';
            app.Image_s1v.Visible = 'off';
            app.Image_s1v.ImageSource = fullfile(pathToMLAPP, 'panels', 's1v.png');
            app.Image_s2v = uiimage(app.GridLayout4);
            app.Image_s2v.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_s2v.Tag = '11';
            app.Image_s2v.Enable = 'off';
            app.Image_s2v.Visible = 'off';
            app.Image_s2v.ImageSource = fullfile(pathToMLAPP, 'panels', 's2v.png');
            app.Image_s3v = uiimage(app.GridLayout4);
            app.Image_s3v.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_s3v.Tag = '12';
            app.Image_s3v.Enable = 'off';
            app.Image_s3v.Visible = 'off';
            app.Image_s3v.ImageSource = fullfile(pathToMLAPP, 'panels', 's3v.png');
            app.Image_m = uiimage(app.GridLayout4);
            app.Image_m.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_m.Tag = '1';
            app.Image_m.Enable = 'off';
            app.Image_m.Visible = 'off';
            app.Image_m.ImageSource = fullfile(pathToMLAPP, 'panels', 'm.png');
            app.Image_s4v = uiimage(app.GridLayout4);
            app.Image_s4v.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_s4v.Tag = '13';
            app.Image_s4v.Enable = 'off';
            app.Image_s4v.Visible = 'off';
            app.Image_s4v.ImageSource = fullfile(pathToMLAPP, 'panels', 's4v.png');
            app.Image_s5v = uiimage(app.GridLayout4);
            app.Image_s5v.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_s5v.Tag = '14';
            app.Image_s5v.Enable = 'off';
            app.Image_s5v.Visible = 'off';
            app.Image_s5v.ImageSource = fullfile(pathToMLAPP, 'panels', 's5v.png');
            app.Image_s6v = uiimage(app.GridLayout4);
            app.Image_s6v.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_s6v.Tag = '15';
            app.Image_s6v.Enable = 'off';
            app.Image_s6v.Visible = 'off';
            app.Image_s6v.ImageSource = fullfile(pathToMLAPP, 'panels', 's6v.png');
            app.Image_g1 = uiimage(app.GridLayout4);
            app.Image_g1.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_g1.Tag = '2';
            app.Image_g1.Enable = 'off';
            app.Image_g1.Visible = 'off';
            app.Image_g1.ImageSource = fullfile(pathToMLAPP, 'panels', 'g1.png');
            app.Image_g2 = uiimage(app.GridLayout4);
            app.Image_g2.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_g2.Tag = '3';
            app.Image_g2.Enable = 'off';
            app.Image_g2.Visible = 'off';
            app.Image_g2.ImageSource = fullfile(pathToMLAPP, 'panels', 'g2.png');
            app.Image_s1h = uiimage(app.GridLayout4);
            app.Image_s1h.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_s1h.Tag = '16';
            app.Image_s1h.Enable = 'off';
            app.Image_s1h.Visible = 'off';
            app.Image_s1h.ImageSource = fullfile(pathToMLAPP, 'panels', 's1h.png');
            app.Image_s2h = uiimage(app.GridLayout4);
            app.Image_s2h.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_s2h.Tag = '17';
            app.Image_s2h.Enable = 'off';
            app.Image_s2h.Visible = 'off';
            app.Image_s2h.ImageSource = fullfile(pathToMLAPP, 'panels', 's2h.png');
            app.Image_g3 = uiimage(app.GridLayout4);
            app.Image_g3.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_g3.Tag = '4';
            app.Image_g3.Enable = 'off';
            app.Image_g3.Visible = 'off';
            app.Image_g3.ImageSource = fullfile(pathToMLAPP, 'panels', 'g3.png');
            app.Image_g4 = uiimage(app.GridLayout4);
            app.Image_g4.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_g4.Tag = '5';
            app.Image_g4.Enable = 'off';
            app.Image_g4.Visible = 'off';
            app.Image_g4.ImageSource = fullfile(pathToMLAPP, 'panels', 'g4.png');
            app.Image_s3h = uiimage(app.GridLayout4);
            app.Image_s3h.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_s3h.Tag = '18';
            app.Image_s3h.Enable = 'off';
            app.Image_s3h.Visible = 'off';
            app.Image_s3h.ImageSource = fullfile(pathToMLAPP, 'panels', 's3h.png');
            app.Image_s4h = uiimage(app.GridLayout4);
            app.Image_s4h.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_s4h.Tag = '19';
            app.Image_s4h.Enable = 'off';
            app.Image_s4h.Visible = 'off';
            app.Image_s4h.ImageSource = fullfile(pathToMLAPP, 'panels', 's4h.png');
            app.Image_c1 = uiimage(app.GridLayout4);
            app.Image_c1.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_c1.Tag = '6';
            app.Image_c1.Enable = 'off';
            app.Image_c1.Visible = 'off';
            app.Image_c1.ImageSource = fullfile(pathToMLAPP, 'panels', 'c1.png');
            app.Image_c2 = uiimage(app.GridLayout4);
            app.Image_c2.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_c2.Tag = '7';
            app.Image_c2.Enable = 'off';
            app.Image_c2.Visible = 'off';
            app.Image_c2.ImageSource = fullfile(pathToMLAPP, 'panels', 'c2.png');
            app.Image_s5h = uiimage(app.GridLayout4);
            app.Image_s5h.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_s5h.Tag = '20';
            app.Image_s5h.Enable = 'off';
            app.Image_s5h.Visible = 'off';
            app.Image_s5h.ImageSource = fullfile(pathToMLAPP, 'panels', 's5h.png');
            app.Image_s6h = uiimage(app.GridLayout4);
            app.Image_s6h.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_s6h.Tag = '21';
            app.Image_s6h.Enable = 'off';
            app.Image_s6h.Visible = 'off';
            app.Image_s6h.ImageSource = fullfile(pathToMLAPP, 'panels', 's6h.png');
            app.Image_c3 = uiimage(app.GridLayout4);
            app.Image_c3.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_c3.Tag = '8';
            app.Image_c3.Enable = 'off';
            app.Image_c3.Visible = 'off';
            app.Image_c3.ImageSource = fullfile(pathToMLAPP, 'panels', 'c3.png');
            app.Image_c4 = uiimage(app.GridLayout4);
            app.Image_c4.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_c4.Tag = '9';
            app.Image_c4.Enable = 'off';
            app.Image_c4.Visible = 'off';
            app.Image_c4.ImageSource = fullfile(pathToMLAPP, 'panels', 'c4.png');
            app.Image_f = uiimage(app.GridLayout4);
            app.Image_f.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_f.Tag = '22';
            app.Image_f.Enable = 'off';
            app.Image_f.Visible = 'off';
            app.Image_f.ImageSource = fullfile(pathToMLAPP, 'panels', 'f.png');
            app.Image_n = uiimage(app.GridLayout4);
            app.Image_n.ImageClickedFcn = createCallbackFcn(app, @RevealReported, true);
            app.Image_n.Tag = 'nan';
            app.Image_n.Enable = 'off';
            app.Image_n.Visible = 'off';
            app.Image_n.ImageSource = fullfile(pathToMLAPP, 'panels', 'n.png');
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.RetellCheckBox = uicheckbox(app.GridLayout6);
            app.RetellCheckBox.ValueChangedFcn = createCallbackFcn(app, @Tell_StateChanged, true);
            app.RetellCheckBox.Text = 'Retell?';
            app.UndoButton = uibutton(app.GridLayout6, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @Undo_UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.ResetButton = uibutton(app.GridLayout6, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Reset_ButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TurnsLabel = uilabel(app.GridLayout6);
            app.TurnsLabel.Text = 'Turns: 11';
            app.NameLabel = uilabel(app.GridLayout6);
            app.NameLabel.Text = '______';
            % Show the figure after all components are created
            app.FauxHollowsSolverUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ffauxivsolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FauxHollowsSolverUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FauxHollowsSolverUIFigure)
        end
    end
end